classdef ra_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        M3_Button               matlab.ui.control.StateButton
        M4_Button               matlab.ui.control.StateButton
        Joint3Slider_2Label     matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        PIDTab                  matlab.ui.container.Tab
        SendKdParametersButton  matlab.ui.control.Button
        SendKiParametersButton  matlab.ui.control.Button
        SendKpParametersButton  matlab.ui.control.Button
        PID_KpTable             matlab.ui.control.Table
        PID_KiTable             matlab.ui.control.Table
        PID_KdTable             matlab.ui.control.Table
        Joint4Slider            matlab.ui.control.Slider
        Joint3Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint1Label             matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        ToggleLEDButton         matlab.ui.control.Button
        COMPortDropDown         matlab.ui.control.DropDown
        COMPortDropDownLabel    matlab.ui.control.Label
        ConnectSwitch           matlab.ui.control.Switch
        M1_Button               matlab.ui.control.StateButton
        M2_Button               matlab.ui.control.StateButton
    end

    properties (Access = public)
        serialObj
        numJoints
    end
    
    methods (Access = private)
        function savePIDdata(app)
            pid_kp = app.PID_KpTable.Data;
            pid_ki = app.PID_KiTable.Data;
            pid_kd = app.PID_KdTable.Data;
            save('mat_data/PID_Matrices.mat', 'pid_kp', 'pid_ki', 'pid_kd');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(fullfile(pwd, 'HelperFunctions')));
            DisableButtons(app);
            populateCOMPorts(app);

            app.numJoints = 4;

            % Load previous controller parameters
            if exist('mat_data/PID_Matrices.mat', 'file')
                data = load('mat_data/PID_Matrices.mat');
                app.PID_KpTable.Data = data.pid_kp;
                app.PID_KiTable.Data = data.pid_ki;
                app.PID_KdTable.Data = data.pid_kd;
            else
                app.PID_KpTable.Data = zeros([1,4]);
                app.PID_KiTable.Data = zeros([1,4]);
                app.PID_KdTable.Data = zeros([1,4]);
            end

        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            handleSerialConnectionChange(app, app.ConnectSwitch.Value == "On");
        end

        % Button pushed function: ToggleLEDButton
        function ToggleLEDButtonPushed(app, event)
            write(app.serialObj, sprintf('TL\n'), 'char');
        end

        % Value changed function: M1_Button, M2_Button, M3_Button, 
        % ...and 1 other component
        function MotorButtonValueChanged(app, event)
            MotorButtonHelperFunction(app, event);
        end

        % Value changed function: Joint1Slider, Joint2Slider, 
        % ...and 2 other components
        function JointSliderValueChanged(app, event)
            JointSliderHelperFunction(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disconnectSerial(app);
            rmpath(genpath(fullfile(pwd, 'HelperFunctions')));
            delete(app);
        end

        % Button pushed function: SendKdParametersButton, 
        % ...and 2 other components
        function SendPIDParamsButtonPushed(app, event)
            savePIDdata(app);
            SendPIDParamsHelperFunction(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.M2_Button = uibutton(app.UIFigure, 'state');
            app.M2_Button.ValueChangedFcn = createCallbackFcn(app, @MotorButtonValueChanged, true);
            app.M2_Button.Text = 'M2_Button';
            app.M1_Button = uibutton(app.UIFigure, 'state');
            app.M1_Button.ValueChangedFcn = createCallbackFcn(app, @MotorButtonValueChanged, true);
            app.M1_Button.Text = 'M1_Button';
            app.ConnectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Value = {};
            app.ToggleLEDButton = uibutton(app.UIFigure, 'push');
            app.ToggleLEDButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleLEDButtonPushed, true);
            app.ToggleLEDButton.Text = 'Toggle LED';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.Joint1Label = uilabel(app.UIFigure);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [1000 3000];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @JointSliderValueChanged, true);
            app.Joint1Slider.Step = 1;
            app.Joint1Slider.Value = 2048;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [1000 3000];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @JointSliderValueChanged, true);
            app.Joint2Slider.Step = 1;
            app.Joint2Slider.Value = 2048;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [1000 3000];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @JointSliderValueChanged, true);
            app.Joint3Slider.Step = 1;
            app.Joint3Slider.Value = 2048;
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [1000 3000];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @JointSliderValueChanged, true);
            app.Joint4Slider.Step = 1;
            app.Joint4Slider.Value = 2048;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.PID_KdTable = uitable(app.PIDTab);
            app.PID_KdTable.ColumnName = {'Kd1'; 'Kd2'; 'Kd3'; 'Kd4'};
            app.PID_KdTable.RowName = {};
            app.PID_KdTable.ColumnEditable = true;
            app.PID_KiTable = uitable(app.PIDTab);
            app.PID_KiTable.ColumnName = {'Ki1'; 'Ki2'; 'Ki3'; 'Ki4'};
            app.PID_KiTable.RowName = {};
            app.PID_KiTable.ColumnEditable = true;
            app.PID_KpTable = uitable(app.PIDTab);
            app.PID_KpTable.ColumnName = {'Kp1'; 'Kp2'; 'Kp3'; 'Kp4'};
            app.PID_KpTable.RowName = {};
            app.PID_KpTable.ColumnEditable = true;
            app.SendKpParametersButton = uibutton(app.PIDTab, 'push');
            app.SendKpParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendPIDParamsButtonPushed, true);
            app.SendKpParametersButton.Text = 'Send Kp Parameters';
            app.SendKiParametersButton = uibutton(app.PIDTab, 'push');
            app.SendKiParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendPIDParamsButtonPushed, true);
            app.SendKiParametersButton.Text = 'Send Ki Parameters';
            app.SendKdParametersButton = uibutton(app.PIDTab, 'push');
            app.SendKdParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendPIDParamsButtonPushed, true);
            app.SendKdParametersButton.Text = 'Send Kd Parameters';
            app.Joint3Slider_2Label = uilabel(app.UIFigure);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.M4_Button = uibutton(app.UIFigure, 'state');
            app.M4_Button.ValueChangedFcn = createCallbackFcn(app, @MotorButtonValueChanged, true);
            app.M4_Button.Text = 'M4_Button';
            app.M3_Button = uibutton(app.UIFigure, 'state');
            app.M3_Button.ValueChangedFcn = createCallbackFcn(app, @MotorButtonValueChanged, true);
            app.M3_Button.Text = 'M3_Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ra_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end