classdef ra_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TrajectoryPanel            matlab.ui.container.Panel
        VisualizeTrajectoryButton  matlab.ui.control.Button
        ComputeTrajectoryButton    matlab.ui.control.Button
        BeginTrajectoryButton      matlab.ui.control.Button
        SendTrajectoryCoefficientsButton  matlab.ui.control.Button
        StopTrajectoryButton       matlab.ui.control.Button
        TrajectoryTextArea         matlab.ui.control.TextArea
        TrajectoryTextAreaLabel    matlab.ui.control.Label
        TrajectoryTable            matlab.ui.control.Table
        TextArea                   matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        ToggleLEDButton            matlab.ui.control.Button
        COMPortDropDown            matlab.ui.control.DropDown
        COMPortDropDownLabel       matlab.ui.control.Label
        ConnectSwitch              matlab.ui.control.Switch
        M1_Button                  matlab.ui.control.StateButton
        M2_Button                  matlab.ui.control.StateButton
        TabGroup                   matlab.ui.container.TabGroup
        PIDTab                     matlab.ui.container.Tab
        SendKdParametersButton     matlab.ui.control.Button
        SendKiParametersButton     matlab.ui.control.Button
        SendKpParametersButton     matlab.ui.control.Button
        PID_KpTable                matlab.ui.control.Table
        PID_KiTable                matlab.ui.control.Table
        PID_KdTable                matlab.ui.control.Table
        GC_PIDTab                  matlab.ui.container.Tab
    end

    properties (Access = private)
        serialObj % Serial object for handling USART communication
        globalRobot
        q
        CoeffData
        tvec

        % dataBuffer = [];     % Stores the streaming data
        % timeBuffer = [];     % Stores corresponding timestamps
        % startTime            % Reference start time
    end
    
    methods (Access = private)
        
        function EnableButtons(app)
            app.ToggleLEDButton.Enable = "on";
            app.M1_Button.Enable = "on";
            app.M2_Button.Enable = "on";

            app.COMPortDropDown.Enable = "off";
        end
        
        function DisableButtons(app)
            app.ToggleLEDButton.Enable = "off";
            app.M1_Button.Enable = "off";
            app.M2_Button.Enable = "off";

            app.COMPortDropDown.Enable = "on";
        end
        
        
        function savePIDdata(app)
            pid_kp = app.PID_KpTable.Data;
            pid_ki = app.PID_KiTable.Data;
            pid_kd = app.PID_KdTable.Data;
            save('mat_data/PID_Matrices.mat', 'pid_kp', 'pid_ki', 'pid_kd');
        end
        
        function saveTrajData(app)
             traj_data = app.TrajectoryTable.Data;
             save('mat_data/Trajectory_Info.mat', 'traj_data');
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appPath = fileparts(mfilename('fullpath'));
            addpath(fullfile(appPath, 'HelperFcns'));
            addpath(fullfile(appPath, 'RoboticsFcns'));
            
            app.globalRobot = RA_CreateRobot(false, false);

            DisableButtons(app);
            
            comPorts = serialportlist("available");  
            
            if isempty(comPorts) % Set dropdown options
                app.COMPortDropDown.Items = {"No COM Ports Found"};
            else
                app.COMPortDropDown.Items = comPorts;
            end
            
            % Load previous controller parameters
            if exist('mat_data\PID_Matrices.mat', 'file')
                data = load('mat_data\PID_Matrices.mat');
                app.PID_KpTable.Data = data.pid_kp;
                app.PID_KiTable.Data = data.pid_ki;
                app.PID_KdTable.Data = data.pid_kd;
            else
                app.PID_KpTable.Data = zeros([4,4]);
                app.PID_KiTable.Data = zeros([4,4]);
                app.PID_KdTable.Data = zeros([4,4]);
            end

            if exist('mat_data\Trajectory_Info.mat', 'file')
                data = load('mat_data\Trajectory_Info.mat');
                app.TrajectoryTable.Data = data.traj_data;
            else
                app.TrajectoryTable.Data = zeros([4,5]);
            end

        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            COMvalue = app.ConnectSwitch.Value;
            if strcmp(COMvalue, "On")
                selectedPort = string(app.COMPortDropDown.Value); 
                
                if selectedPort == "No COM Ports Found" || selectedPort == ""
                    uialert(app.UIFigure, "No valid COM port selected!", "Error");
                    app.ConnectSwitch.Value = "Off";
                    return;
                end

                if ~isempty(app.serialObj) % 🛑 Close any previous connections
                    delete(app.serialObj);
                    app.serialObj = [];
                    pause(1);  % Wait a bit before reopening
                end

                try % ✅ Try to open port
                    app.serialObj = serialport(selectedPort, 115200, "Timeout", 2);

                    configureTerminator(app.serialObj, "LF");
                    configureCallback(app.serialObj, "terminator", @(src,~) processSerialData(src, app));

                    disp("Successfully opened port: " + selectedPort);
                    
                    % Enable all usuable buttons now
                    app.ConnectSwitch.Value = "On";
                    EnableButtons(app);
                catch ME
                    disp("ERROR: " + ME.message);
                    uialert(app.UIFigure, "Failed to open COM port! " + ME.message, "Error");
                    app.ConnectSwitch.Value = "Off"; 
                end
            else              
                app.ConnectSwitch.Value = "Off"; % 🛑 Disconnect
                if ~isempty(app.serialObj)
                    delete(app.serialObj);
                    app.serialObj = [];
                end
                
                % Disable unusable buttons at this stage
                DisableButtons(app);
            end
        end

        % Button pushed function: ToggleLEDButton
        function ToggleLEDButtonPushed(app, event)
            writeline(app.serialObj, "TL"); % Send command to toggle LED
        end

        % Value changed function: M1_Button
        function M1_ButtonValueChanged(app, event)
            value = app.M1_Button.Value;
            msg = "MS0" + string(int8(value));
            writeline(app.serialObj, msg);
        end

        % Value changed function: M2_Button
        function M2_ButtonValueChanged(app, event)
            value = app.M2_Button.Value;
            msg = "MS1" + string(int8(value));
            writeline(app.serialObj, msg);
        end

        % Button pushed function: SendKpParametersButton
        function SendKpParametersButtonPushed(app, event)
            savePIDdata(app);
            pid_kp_data = app.PID_KpTable.Data;
            sendControllerParameters(app.serialObj, pid_kp_data, 'KP');
        end

        % Button pushed function: SendKiParametersButton
        function SendKiParametersButtonPushed(app, event)
            savePIDdata(app);
            pid_ki_data = app.PID_KiTable.Data;
            sendControllerParameters(app.serialObj, pid_ki_data, 'KI');
        end

        % Button pushed function: SendKdParametersButton
        function SendKdParametersButtonPushed(app, event)
            savePIDdata(app);
            pid_kd_data = app.PID_KdTable.Data;
            sendControllerParameters(app.serialObj, pid_kd_data, 'KD');
        end

        % Button pushed function: BeginTrajectoryButton
        function BeginTrajectoryButtonPushed(app, event)
            writeline(app.serialObj, "ST");
        end

        % Button pushed function: ComputeTrajectoryButton
        function ComputeTrajectoryButtonPushed(app, event)
            saveTrajData(app);
           
            disp("Computing trajectory...");

            wptsApp = app.TrajectoryTable.Data(1:3, :);
            tptsApp = app.TrajectoryTable.Data(4, :);
            [app.CoeffData, app.tvec, app.q, ~, ~] = RA_GenerateJointTrajectory(app.globalRobot, wptsApp, tptsApp, [0 0]);
            disp("Trajectory computed.");
        end

        % Button pushed function: VisualizeTrajectoryButton
        function VisualizeTrajectoryButtonPushed(app, event)
            wptsApp = app.TrajectoryTable.Data(1:3, :);
            tptsApp = app.TrajectoryTable.Data(4, :);
            
            disp("Plotting trajectory...");
            RA_PlotTrajectory(app.globalRobot, app.q, app.tvec, wptsApp, tptsApp);
            disp("Trajectory plotted.");
        end

        % Button pushed function: SendTrajectoryCoefficientsButton
        function SendTrajectoryCoefficientsButtonPushed(app, event)
            tptsApp = app.TrajectoryTable.Data(4, :);

            disp("Sending coefficients...");
            sendTrajData(app.serialObj, app.CoeffData, tptsApp);
            disp("Sending coefficients...");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            appPath = fileparts(mfilename('fullpath'));
            rmpath(fullfile(appPath, 'HelperFcns'));
            rmpath(fullfile(appPath, 'RoboticsFcns'));
                    
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.PID_KdTable = uitable(app.PIDTab);
            app.PID_KdTable.ColumnName = {'Kd1'; 'Kd2'; 'Kd3'; 'Kd4'};
            app.PID_KdTable.RowName = {};
            app.PID_KdTable.ColumnEditable = true;
            app.PID_KiTable = uitable(app.PIDTab);
            app.PID_KiTable.ColumnName = {'Ki1'; 'Ki2'; 'Ki3'; 'Ki4'};
            app.PID_KiTable.RowName = {};
            app.PID_KiTable.ColumnEditable = true;
            app.PID_KpTable = uitable(app.PIDTab);
            app.PID_KpTable.ColumnName = {'Kp1'; 'Kp2'; 'Kp3'; 'Kp4'};
            app.PID_KpTable.RowName = {};
            app.PID_KpTable.ColumnEditable = true;
            app.SendKpParametersButton = uibutton(app.PIDTab, 'push');
            app.SendKpParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendKpParametersButtonPushed, true);
            app.SendKpParametersButton.Text = 'Send Kp Parameters';
            app.SendKiParametersButton = uibutton(app.PIDTab, 'push');
            app.SendKiParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendKiParametersButtonPushed, true);
            app.SendKiParametersButton.Text = 'Send Ki Parameters';
            app.SendKdParametersButton = uibutton(app.PIDTab, 'push');
            app.SendKdParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SendKdParametersButtonPushed, true);
            app.SendKdParametersButton.Text = 'Send Kd Parameters';
            app.GC_PIDTab = uitab(app.TabGroup);
            app.GC_PIDTab.Title = 'GC_PID';
            app.M2_Button = uibutton(app.UIFigure, 'state');
            app.M2_Button.ValueChangedFcn = createCallbackFcn(app, @M2_ButtonValueChanged, true);
            app.M2_Button.Text = 'M2_Button';
            app.M1_Button = uibutton(app.UIFigure, 'state');
            app.M1_Button.ValueChangedFcn = createCallbackFcn(app, @M1_ButtonValueChanged, true);
            app.M1_Button.Text = 'M1_Button';
            app.ConnectSwitch = uiswitch(app.UIFigure, 'slider');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Value = {};
            app.ToggleLEDButton = uibutton(app.UIFigure, 'push');
            app.ToggleLEDButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleLEDButtonPushed, true);
            app.ToggleLEDButton.Text = 'Toggle LED';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TrajectoryPanel = uipanel(app.UIFigure);
            app.TrajectoryPanel.Title = 'Trajectory Panel';
            app.TrajectoryTable = uitable(app.TrajectoryPanel);
            app.TrajectoryTable.ColumnName = {'Data1'; 'Data2'; 'Data3'; 'Data4'; 'Data5'};
            app.TrajectoryTable.RowName = {'X'; 'Y'; 'Z'; 'T'};
            app.TrajectoryTable.ColumnEditable = true;
            app.TrajectoryTextAreaLabel = uilabel(app.TrajectoryPanel);
            app.TrajectoryTextAreaLabel.Text = 'Trajectory Text Area';
            app.TrajectoryTextArea = uitextarea(app.TrajectoryPanel);
            app.StopTrajectoryButton = uibutton(app.TrajectoryPanel, 'push');
            app.StopTrajectoryButton.Text = {'Stop'; 'Trajectory'};
            app.SendTrajectoryCoefficientsButton = uibutton(app.TrajectoryPanel, 'push');
            app.SendTrajectoryCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @SendTrajectoryCoefficientsButtonPushed, true);
            app.SendTrajectoryCoefficientsButton.Text = {'Send Trajectory'; 'Coefficients'};
            app.BeginTrajectoryButton = uibutton(app.TrajectoryPanel, 'push');
            app.BeginTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @BeginTrajectoryButtonPushed, true);
            app.BeginTrajectoryButton.Text = {'Begin'; 'Trajectory'};
            app.ComputeTrajectoryButton = uibutton(app.TrajectoryPanel, 'push');
            app.ComputeTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeTrajectoryButtonPushed, true);
            app.ComputeTrajectoryButton.Text = {'Compute'; 'Trajectory'};
            app.VisualizeTrajectoryButton = uibutton(app.TrajectoryPanel, 'push');
            app.VisualizeTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeTrajectoryButtonPushed, true);
            app.VisualizeTrajectoryButton.Text = {'Visualize'; 'Trajectory'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ra_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end